classdef CraneControl < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SimulaciaButton                 matlab.ui.control.Button
        reg                             matlab.ui.container.Panel
        DropDown_3                      matlab.ui.control.DropDown
        VstupnaveliinaPanel_2           matlab.ui.container.Panel
        u                               matlab.ui.control.NumericEditField
        SilapsobiacanavozkuEditFieldLabel  matlab.ui.control.Label
        VstupnaveliinaPanel             matlab.ui.container.Panel
        V                               matlab.ui.control.NumericEditField
        NaptieVEditFieldLabel           matlab.ui.control.Label
        ParametremotoraPanel            matlab.ui.container.Panel
        B                               matlab.ui.control.NumericEditField
        KoeficienttreniaLabel           matlab.ui.control.Label
        L                               matlab.ui.control.NumericEditField
        InduknosobmotaniamotoraLmLabel  matlab.ui.control.Label
        R                               matlab.ui.control.NumericEditField
        OdporobmotaniamotoraREditFieldLabel  matlab.ui.control.Label
        r                               matlab.ui.control.NumericEditField
        PolomerkladkyrEditFieldLabel    matlab.ui.control.Label
        Ke                              matlab.ui.control.NumericEditField
        SptnaelektromotorickasilaKeEditFieldLabel  matlab.ui.control.Label
        Kt                              matlab.ui.control.NumericEditField
        KontantakrtiacehomomentumotoraKtLabel  matlab.ui.control.Label
        NastaveniasystemuPanel          matlab.ui.container.Panel
        g                               matlab.ui.control.NumericEditField
        GravitanzrchleniegEditFieldLabel  matlab.ui.control.Label
        l                               matlab.ui.control.NumericEditField
        DkalanalEditFieldLabel          matlab.ui.control.Label
        m1                              matlab.ui.control.NumericEditField
        Hmotnosbremenam1EditFieldLabel  matlab.ui.control.Label
        m0                              matlab.ui.control.NumericEditField
        Hmotnosvozkam0Label             matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        RiadeniemodeluportlovhoeriavuLabel  matlab.ui.control.Label
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SimulaciaButton
        function SimulaciaButtonPushed(app, event)
            simOut = sim('Control.slx');
            if app.DropDown_3.Value == "LQR"
                poloha = simOut.sql_p.Data;
                t_p = simOut.sql_p.Time;
                uhol = simOut.sql_u.Data;
                u_t = simOut.sql_u.Time;
                plot(app.UIAxes, t_p, poloha);
                title(app.UIAxes, 'Poloha vozika pri LQR regulatore');
                xlabel(app.UIAxes, 'Čas [s]');
                ylabel(app.UIAxes, 'Poloha [m]');
                plot(app.UIAxes2, u_t, uhol);
                title(app.UIAxes2, 'Uhol vychylenia pri LQR regulatore');
                xlabel(app.UIAxes2, 'Čas [s]');
                ylabel(app.UIAxes2, 'Uhol [rad]');
            else
                poloha = simOut.ljap_p.Data;
                t_p = simOut.ljap_p.Time;
                uhol = simOut.ljap_u.Data;
                u_t = simOut.ljap_u.Time;
                plot(app.UIAxes, t_p, poloha);
                title(app.UIAxes, 'Poloha vozika pre regulátor založený na Ljapunovovej funkcii');
                xlabel(app.UIAxes, 'Čas [s]');
                ylabel(app.UIAxes, 'Poloha [m]');
                plot(app.UIAxes2, u_t, uhol);
                title(app.UIAxes2, 'Uhol vychylenia pre regulátor založený na Ljapunovovej funkcii');
                xlabel(app.UIAxes2, 'Čas [s]');
                ylabel(app.UIAxes2, 'Uhol [rad]');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.RiadeniemodeluportlovhoeriavuLabel = uilabel(app.UIFigure);
            app.RiadeniemodeluportlovhoeriavuLabel.Text = 'Riadenie modelu portálového žeriavu';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'portalovy_zeriav_schema.png';
            app.NastaveniasystemuPanel = uipanel(app.UIFigure);
            app.NastaveniasystemuPanel.Title = 'Nastavenia systemu';
            app.Hmotnosvozkam0Label = uilabel(app.NastaveniasystemuPanel);
            app.Hmotnosvozkam0Label.Text = 'Hmotnosť vozíka  m0 ';
            app.m0 = uieditfield(app.NastaveniasystemuPanel, 'numeric');
            app.m0.Value = 100;
            app.Hmotnosbremenam1EditFieldLabel = uilabel(app.NastaveniasystemuPanel);
            app.Hmotnosbremenam1EditFieldLabel.Text = 'Hmotnosť bremena  m1  ';
            app.m1 = uieditfield(app.NastaveniasystemuPanel, 'numeric');
            app.m1.Value = 50;
            app.DkalanalEditFieldLabel = uilabel(app.NastaveniasystemuPanel);
            app.DkalanalEditFieldLabel.Text = 'Dĺžka lana  l';
            app.l = uieditfield(app.NastaveniasystemuPanel, 'numeric');
            app.l.Value = 2;
            app.GravitanzrchleniegEditFieldLabel = uilabel(app.NastaveniasystemuPanel);
            app.GravitanzrchleniegEditFieldLabel.Text = 'Gravitačné zrýchlenie  g';
            app.g = uieditfield(app.NastaveniasystemuPanel, 'numeric');
            app.g.Value = 9.81;
            app.ParametremotoraPanel = uipanel(app.UIFigure);
            app.ParametremotoraPanel.Title = 'Parametre motora';
            app.KontantakrtiacehomomentumotoraKtLabel = uilabel(app.ParametremotoraPanel);
            app.KontantakrtiacehomomentumotoraKtLabel.Text = 'Krútiacy moment motora  Kt';
            app.Kt = uieditfield(app.ParametremotoraPanel, 'numeric');
            app.Kt.Value = 0.1;
            app.SptnaelektromotorickasilaKeEditFieldLabel = uilabel(app.ParametremotoraPanel);
            app.SptnaelektromotorickasilaKeEditFieldLabel.Text = 'Spätna elektromotoricka sila  Ke';
            app.Ke = uieditfield(app.ParametremotoraPanel, 'numeric');
            app.Ke.Value = 0.1;
            app.PolomerkladkyrEditFieldLabel = uilabel(app.ParametremotoraPanel);
            app.PolomerkladkyrEditFieldLabel.Text = 'Polomer kladky r';
            app.r = uieditfield(app.ParametremotoraPanel, 'numeric');
            app.r.Value = 0.05;
            app.OdporobmotaniamotoraREditFieldLabel = uilabel(app.ParametremotoraPanel);
            app.OdporobmotaniamotoraREditFieldLabel.Text = 'Odpor obmotania motora  R';
            app.R = uieditfield(app.ParametremotoraPanel, 'numeric');
            app.R.Value = 1;
            app.InduknosobmotaniamotoraLmLabel = uilabel(app.ParametremotoraPanel);
            app.InduknosobmotaniamotoraLmLabel.Text = 'Indukčnosť obmotania motora Lm';
            app.L = uieditfield(app.ParametremotoraPanel, 'numeric');
            app.L.Value = 0.01;
            app.KoeficienttreniaLabel = uilabel(app.ParametremotoraPanel);
            app.KoeficienttreniaLabel.Text = 'Koeficient trenia B';
            app.B = uieditfield(app.ParametremotoraPanel, 'numeric');
            app.B.Value = 0.1;
            app.VstupnaveliinaPanel = uipanel(app.UIFigure);
            app.VstupnaveliinaPanel.Title = 'Vstupna veličina';
            app.NaptieVEditFieldLabel = uilabel(app.VstupnaveliinaPanel);
            app.NaptieVEditFieldLabel.Text = 'Napätie  V';
            app.V = uieditfield(app.VstupnaveliinaPanel, 'numeric');
            app.V.Value = 24;
            app.VstupnaveliinaPanel_2 = uipanel(app.UIFigure);
            app.VstupnaveliinaPanel_2.Title = 'Vstupna veličina';
            app.VstupnaveliinaPanel_2.Visible = 'off';
            app.SilapsobiacanavozkuEditFieldLabel = uilabel(app.VstupnaveliinaPanel_2);
            app.SilapsobiacanavozkuEditFieldLabel.Text = 'Sila pôsobiaca na vozík  u';
            app.u = uieditfield(app.VstupnaveliinaPanel_2, 'numeric');
            app.u.Value = 100;
            app.reg = uipanel(app.UIFigure);
            app.reg.Title = 'Vyber regulatora';
            app.DropDown_3 = uidropdown(app.reg);
            app.DropDown_3.Items = {'LQR', 'Regulator s využitím Ljapunovovej funkcie'};
            app.DropDown_3.Value = 'LQR';
            app.SimulaciaButton = uibutton(app.UIFigure, 'push');
            app.SimulaciaButton.ButtonPushedFcn = createCallbackFcn(app, @SimulaciaButtonPushed, true);
            app.SimulaciaButton.Text = 'Simulacia';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CraneControl

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end