classdef CranePhasePortrait < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        VstupnaveliinaPanel             matlab.ui.container.Panel
        u                               matlab.ui.control.NumericEditField
        SilapsobiacanavozkuEditFieldLabel  matlab.ui.control.Label
        VstupnaveliinaPanel_2           matlab.ui.container.Panel
        V                               matlab.ui.control.NumericEditField
        NaptieVEditFieldLabel           matlab.ui.control.Label
        ParametremotoraPanel            matlab.ui.container.Panel
        B                               matlab.ui.control.NumericEditField
        KoeficienttreniaLabel           matlab.ui.control.Label
        L                               matlab.ui.control.NumericEditField
        InduknosobmotaniamotoraLmLabel  matlab.ui.control.Label
        R                               matlab.ui.control.NumericEditField
        OdporobmotaniamotoraREditFieldLabel  matlab.ui.control.Label
        r                               matlab.ui.control.NumericEditField
        PolomerkladkyrEditFieldLabel    matlab.ui.control.Label
        Ke                              matlab.ui.control.NumericEditField
        SptnaelektromotorickasilaKeEditFieldLabel  matlab.ui.control.Label
        Kt                              matlab.ui.control.NumericEditField
        KontantakrtiacehomomentumotoraKtLabel  matlab.ui.control.Label
        SimulaciaButton                 matlab.ui.control.Button
        VybersystemuPanel               matlab.ui.container.Panel
        DropDown_2                      matlab.ui.control.DropDown
        DropDown                        matlab.ui.control.DropDown
        NastaveniasystemuPanel          matlab.ui.container.Panel
        g                               matlab.ui.control.NumericEditField
        GravitanzrchleniegEditFieldLabel  matlab.ui.control.Label
        l                               matlab.ui.control.NumericEditField
        DkalanalEditFieldLabel          matlab.ui.control.Label
        m1                              matlab.ui.control.NumericEditField
        Hmotnosbremenam1EditFieldLabel  matlab.ui.control.Label
        m0                              matlab.ui.control.NumericEditField
        Hmotnosvozkam0Label             matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        FzovportrteriavuLabel           matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SimulaciaButton
        function SimulaciaButtonPushed(app, event)
            if app.DropDown_2.Value == "Bez motora"
                    if app.DropDown.Value == "Nelineárny model"
                        fp_without_motor(app.m0.Value, app.m1.Value, app.l.Value, app.g.Value, app.u.Value, 0)
                    else
                        fp_without_motor(app.m0.Value, app.m1.Value, app.l.Value, app.g.Value, app.u.Value, 1)
                    end
            else
                    if app.DropDown.Value == "Nelineárny model"
                        fp_with_motor(app.m0.Value, app.m1.Value, app.l.Value, app.g.Value, app.Kt.Value, app.Ke.Value, app.R.Value, app.L.Value, app.r.Value, app.V.Value, app.B.Value, 0)
                    else
                        fp_with_motor(app.m0.Value, app.m1.Value, app.l.Value, app.g.Value, app.Kt.Value, app.Ke.Value, app.R.Value, app.L.Value, app.r.Value, app.V.Value, app.B.Value, 1)
                    end
            end

        end

        % Value changed function: DropDown_2
        function DropDown_2ValueChanged(app, event)
     
            if app.DropDown_2.Value == "Bez motora"
                app.ParametremotoraPanel.Visible = 0;
                app.VstupnaveliinaPanel.Visible = 1;
                app.VstupnaveliinaPanel_2.Visible = 0;
            else
                app.ParametremotoraPanel.Visible = 1;
                app.VstupnaveliinaPanel.Visible = 0;
                app.VstupnaveliinaPanel_2.Visible = 1;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FzovportrteriavuLabel = uilabel(app.UIFigure);
            app.FzovportrteriavuLabel.Text = 'Fázový portrét žeriavu ';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'fig', 'portalovy_zeriav_schema.png');
            app.NastaveniasystemuPanel = uipanel(app.UIFigure);
            app.NastaveniasystemuPanel.Title = 'Nastavenia systemu';
            app.Hmotnosvozkam0Label = uilabel(app.NastaveniasystemuPanel);
            app.Hmotnosvozkam0Label.Text = 'Hmotnosť vozíka  m0 ';
            app.m0 = uieditfield(app.NastaveniasystemuPanel, 'numeric');
            app.m0.Value = 100;
            app.Hmotnosbremenam1EditFieldLabel = uilabel(app.NastaveniasystemuPanel);
            app.Hmotnosbremenam1EditFieldLabel.Text = 'Hmotnosť bremena  m1  ';
            app.m1 = uieditfield(app.NastaveniasystemuPanel, 'numeric');
            app.m1.Value = 50;
            app.DkalanalEditFieldLabel = uilabel(app.NastaveniasystemuPanel);
            app.DkalanalEditFieldLabel.Text = 'Dĺžka lana  l';
            app.l = uieditfield(app.NastaveniasystemuPanel, 'numeric');
            app.l.Value = 2;
            app.GravitanzrchleniegEditFieldLabel = uilabel(app.NastaveniasystemuPanel);
            app.GravitanzrchleniegEditFieldLabel.Text = 'Gravitačné zrýchlenie  g';
            app.g = uieditfield(app.NastaveniasystemuPanel, 'numeric');
            app.g.Value = 9.81;
            app.VybersystemuPanel = uipanel(app.UIFigure);
            app.VybersystemuPanel.Title = 'Vyber systemu';
            app.DropDown = uidropdown(app.VybersystemuPanel);
            app.DropDown.Items = {'Nelineárny model', 'Linearizovaný model'};
            app.DropDown.Value = 'Nelineárny model';
            app.DropDown_2 = uidropdown(app.VybersystemuPanel);
            app.DropDown_2.Items = {'S motorom', 'Bez motora'};
            app.DropDown_2.ValueChangedFcn = createCallbackFcn(app, @DropDown_2ValueChanged, true);
            app.DropDown_2.Value = 'S motorom';
            app.SimulaciaButton = uibutton(app.UIFigure, 'push');
            app.SimulaciaButton.ButtonPushedFcn = createCallbackFcn(app, @SimulaciaButtonPushed, true);
            app.SimulaciaButton.Text = 'Simulacia';
            app.ParametremotoraPanel = uipanel(app.UIFigure);
            app.ParametremotoraPanel.Title = 'Parametre motora';
            app.KontantakrtiacehomomentumotoraKtLabel = uilabel(app.ParametremotoraPanel);
            app.KontantakrtiacehomomentumotoraKtLabel.Text = 'Krútiacy moment motora  Kt';
            app.Kt = uieditfield(app.ParametremotoraPanel, 'numeric');
            app.Kt.Value = 0.1;
            app.SptnaelektromotorickasilaKeEditFieldLabel = uilabel(app.ParametremotoraPanel);
            app.SptnaelektromotorickasilaKeEditFieldLabel.Text = 'Spätna elektromotoricka sila  Ke';
            app.Ke = uieditfield(app.ParametremotoraPanel, 'numeric');
            app.Ke.Value = 0.1;
            app.PolomerkladkyrEditFieldLabel = uilabel(app.ParametremotoraPanel);
            app.PolomerkladkyrEditFieldLabel.Text = 'Polomer kladky r';
            app.r = uieditfield(app.ParametremotoraPanel, 'numeric');
            app.r.Value = 0.05;
            app.OdporobmotaniamotoraREditFieldLabel = uilabel(app.ParametremotoraPanel);
            app.OdporobmotaniamotoraREditFieldLabel.Text = 'Odpor obmotania motora  R';
            app.R = uieditfield(app.ParametremotoraPanel, 'numeric');
            app.R.Value = 1;
            app.InduknosobmotaniamotoraLmLabel = uilabel(app.ParametremotoraPanel);
            app.InduknosobmotaniamotoraLmLabel.Text = 'Indukčnosť obmotania motora Lm';
            app.L = uieditfield(app.ParametremotoraPanel, 'numeric');
            app.L.Value = 0.01;
            app.KoeficienttreniaLabel = uilabel(app.ParametremotoraPanel);
            app.KoeficienttreniaLabel.Text = 'Koeficient trenia B';
            app.B = uieditfield(app.ParametremotoraPanel, 'numeric');
            app.B.Value = 0.1;
            app.VstupnaveliinaPanel_2 = uipanel(app.UIFigure);
            app.VstupnaveliinaPanel_2.Title = 'Vstupna veličina';
            app.NaptieVEditFieldLabel = uilabel(app.VstupnaveliinaPanel_2);
            app.NaptieVEditFieldLabel.Text = 'Napätie  V';
            app.V = uieditfield(app.VstupnaveliinaPanel_2, 'numeric');
            app.V.Value = 24;
            app.VstupnaveliinaPanel = uipanel(app.UIFigure);
            app.VstupnaveliinaPanel.Title = 'Vstupna veličina';
            app.VstupnaveliinaPanel.Visible = 'off';
            app.SilapsobiacanavozkuEditFieldLabel = uilabel(app.VstupnaveliinaPanel);
            app.SilapsobiacanavozkuEditFieldLabel.Text = 'Sila pôsobiaca na vozík  u';
            app.u = uieditfield(app.VstupnaveliinaPanel, 'numeric');
            app.u.Value = 100;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CranePhasePortrait

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end