classdef SpringDamperPortrait < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Image                       matlab.ui.control.Image
        NastaveniasystemuPanel      matlab.ui.container.Panel
        u                           matlab.ui.control.NumericEditField
        BudenieuLabel               matlab.ui.control.Label
        x10x20EditField             matlab.ui.control.NumericEditField
        x10x20EditFieldLabel        matlab.ui.control.Label
        a                           matlab.ui.control.NumericEditField
        KoeficientnelinearityEditFieldLabel  matlab.ui.control.Label
        k                           matlab.ui.control.NumericEditField
        TuhospruinykEditFieldLabel  matlab.ui.control.Label
        b                           matlab.ui.control.NumericEditField
        KoeficienttlmeniacLabel     matlab.ui.control.Label
        m                           matlab.ui.control.NumericEditField
        HmotnosmLabel               matlab.ui.control.Label
        SimulaciaButton             matlab.ui.control.Button
        OdvetviemetodikypretvorbufzovhoportrtuButtonGroup  matlab.ui.container.ButtonGroup
        VetvaBButton                matlab.ui.control.RadioButton
        VetvaAButton                matlab.ui.control.RadioButton
        FzovportrtnelinernejpruinyatlmiaLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SimulaciaButton
        function SimulaciaButtonPushed(app, event)
            if app.VetvaAButton.Value == 1
                method = 0;
                PaT(app.m.Value, app.b.Value, app.k.Value, app.u.Value, app.a.Value,method);
            else 
                method = 1;
                PaT(app.m.Value, app.b.Value, app.k.Value, app.u.Value, app.a.Value,method);
            end    
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FzovportrtnelinernejpruinyatlmiaLabel = uilabel(app.UIFigure);
            app.FzovportrtnelinernejpruinyatlmiaLabel.Text = 'Fázový portrét nelineárnej pružiny a tlmiča';
            app.OdvetviemetodikypretvorbufzovhoportrtuButtonGroup = uibuttongroup(app.UIFigure);
            app.OdvetviemetodikypretvorbufzovhoportrtuButtonGroup.Title = 'Odvetvie metodiky pre tvorbu fázového portrétu:';
            app.VetvaAButton = uiradiobutton(app.OdvetviemetodikypretvorbufzovhoportrtuButtonGroup);
            app.VetvaAButton.Text = 'Vetva A';
            app.VetvaAButton.Value = true;
            app.VetvaBButton = uiradiobutton(app.OdvetviemetodikypretvorbufzovhoportrtuButtonGroup);
            app.VetvaBButton.Text = 'Vetva B';
            app.SimulaciaButton = uibutton(app.UIFigure, 'push');
            app.SimulaciaButton.ButtonPushedFcn = createCallbackFcn(app, @SimulaciaButtonPushed, true);
            app.SimulaciaButton.Text = 'Simulacia';
            app.NastaveniasystemuPanel = uipanel(app.UIFigure);
            app.NastaveniasystemuPanel.Title = 'Nastavenia systemu';
            app.HmotnosmLabel = uilabel(app.NastaveniasystemuPanel);
            app.HmotnosmLabel.Text = 'Hmotnosť  m';
            app.m = uieditfield(app.NastaveniasystemuPanel, 'numeric');
            app.m.Value = 5;
            app.KoeficienttlmeniacLabel = uilabel(app.NastaveniasystemuPanel);
            app.KoeficienttlmeniacLabel.Text = 'Koeficient tlmenia  c';
            app.b = uieditfield(app.NastaveniasystemuPanel, 'numeric');
            app.b.Value = 0.5;
            app.TuhospruinykEditFieldLabel = uilabel(app.NastaveniasystemuPanel);
            app.TuhospruinykEditFieldLabel.Text = 'Tuhosť pružiny k';
            app.k = uieditfield(app.NastaveniasystemuPanel, 'numeric');
            app.k.Value = 0.2;
            app.KoeficientnelinearityEditFieldLabel = uilabel(app.NastaveniasystemuPanel);
            app.KoeficientnelinearityEditFieldLabel.Text = 'Koeficient nelinearity';
            app.a = uieditfield(app.NastaveniasystemuPanel, 'numeric');
            app.a.Value = 0.02;
            app.x10x20EditFieldLabel = uilabel(app.NastaveniasystemuPanel);
            app.x10x20EditFieldLabel.Text = '[x1(0), x2(0)]';
            app.x10x20EditField = uieditfield(app.NastaveniasystemuPanel, 'numeric');
            app.BudenieuLabel = uilabel(app.NastaveniasystemuPanel);
            app.BudenieuLabel.Text = 'Budenie u';
            app.u = uieditfield(app.NastaveniasystemuPanel, 'numeric');
            app.u.Value = 1;
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'fig', 'Mass_spring_damper.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SpringDamperPortrait

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end